classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClassificatoreButton            matlab.ui.control.Button
        SelezionarumoreDropDownLabel    matlab.ui.control.Label
        SelezionarumoreDropDown         matlab.ui.control.DropDown
        SelezionacellsizeDropDownLabel  matlab.ui.control.Label
        SelezionacellsizeDropDown       matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Button pushed function: ClassificatoreButton
        function classificatore(app, event)
            imagespath=dir('C:\Users\Simone\Desktop\uni\immagini\database per gli elaborati di Tipo 2-20190208\Yale\Yale');
imagespath;
ImagesPath=imagespath(4:end,:);% Sono eliminati i primi tre elementi perche' non sono immagini

% creiamo una struct images in cui inserire solo le immagini(unit8) e le tipologie(char)/classi
images=struct;
for i=1:size(ImagesPath,1)
    images(i).images=imread([ImagesPath(i).folder,'/',ImagesPath(i).name]);
    images(i).tipologia = extractAfter(ImagesPath(i).name,".");
end

          for i=1:size(images,2) %164 immagini
              rum = getappdata(0,'rumore');
              cell_size = getappdata(0,'cell_size');
           features(i).features = extractHOGFeatures((double(images(i).images)+sqrt(rum(1))*randn(size(images(i).images))), ...
               'CellSize',[cell_size(1) cell_size(1)]);
          end      
          v = evalin('base', 'classifier');
          test_feature =  features(1).features;
          predizioni= predict(v,  test_feature)
          
    
        end

        % Value changed function: SelezionarumoreDropDown
        function selezione_rumore(app, event)
            value = app.SelezionarumoreDropDown.Value;
            setappdata(0,'rumore',str2num(value));
           
        end

        % Value changed function: SelezionacellsizeDropDown
        function selezione_cell_size(app, event)
            cell_size = app.SelezionacellsizeDropDown.Value;
            setappdata(0,'cell_size',str2num(cell_size));
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ClassificatoreButton = uibutton(app.UIFigure, 'push');
            app.ClassificatoreButton.ButtonPushedFcn = createCallbackFcn(app, @classificatore, true);
            app.ClassificatoreButton.Text = 'Classificatore';
            app.SelezionarumoreDropDownLabel = uilabel(app.UIFigure);
            app.SelezionarumoreDropDownLabel.Text = 'Seleziona rumore';
            app.SelezionarumoreDropDown = uidropdown(app.UIFigure);
            app.SelezionarumoreDropDown.Items = {'0', '0.0001', '0.001', '0.01', '0.1'};
            app.SelezionarumoreDropDown.ValueChangedFcn = createCallbackFcn(app, @selezione_rumore, true);
            app.SelezionarumoreDropDown.Value = '0';
            app.SelezionacellsizeDropDownLabel = uilabel(app.UIFigure);
            app.SelezionacellsizeDropDownLabel.Text = 'Seleziona cell size';
            app.SelezionacellsizeDropDown = uidropdown(app.UIFigure);
            app.SelezionacellsizeDropDown.Items = {'2', '4', '8'};
            app.SelezionacellsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @selezione_cell_size, true);
            app.SelezionacellsizeDropDown.Value = '2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end